classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        OptimizationselectionDropDownLabel  matlab.ui.control.Label
        OptimizationselectionDropDown  matlab.ui.control.DropDown
        ProblemselectionDropDownLabel  matlab.ui.control.Label
        ProblemselectionDropDown       matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OptimizationselectionDropDown, 
        % ProblemselectionDropDown
        function OptimizationselectionDropDownValueChanged(app, event)
            selOpt = app.OptimizationselectionDropDown.Value;
            selProblem = app.ProblemselectionDropDown.Value;            
            app.UIAxes.cla;
            reset(app.UIAxes)
            switch selProblem
                case 'Sphere Function'
                    plotLabel = 'Sphere Function';
                case 'Rotated High Conditioned Elliptic Function'
                    plotLabel = 'Rotated High Conditioned Elliptic Function';
                case 'Rotated Bent Cigar Function'
                    plotLabel = 'Rotated Bent Cigar Function';
                case 'Rotated Discus Function'
                    plotLabel = 'Rotated Discus Function';
                case 'Different Powers Function'
                    plotLabel = 'Different Powers Function';
            end
            title(app.UIAxes,plotLabel)
            switch selOpt
                case 'Nelder-Mead'
                    plot(app.UIAxes,rand(5));                    
                case 'Hooke-Jeeves'
                    plot(app.UIAxes,sin(1:0.01:25.99));                    
                case 'Implicit Filtering'
                    bar(app.UIAxes,1:.5:10);
                case 'Multidirectional Search'
                    plot(app.UIAxes,membrane);
                case 'Pattern Search'
                    surf(app.UIAxes,peaks);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.OptimizationselectionDropDownLabel = uilabel(app.UIFigure);
            app.OptimizationselectionDropDownLabel.Text = 'Optimization selection';
            app.OptimizationselectionDropDown = uidropdown(app.UIFigure);
            app.OptimizationselectionDropDown.Items = {'Nelder-Mead', 'Hooke-Jeeves', 'Implicit Filtering', 'Multidirectional Search', 'Pattern Search'};
            app.OptimizationselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationselectionDropDownValueChanged, true);
            app.OptimizationselectionDropDown.Value = 'Nelder-Mead';
            app.ProblemselectionDropDownLabel = uilabel(app.UIFigure);
            app.ProblemselectionDropDownLabel.Text = 'Problem selection';
            app.ProblemselectionDropDown = uidropdown(app.UIFigure);
            app.ProblemselectionDropDown.Items = {'Sphere Function', 'Rotated High Conditioned Elliptic Function', 'Rotated Bent Cigar Function', 'Rotated Discus Function', 'Different Powers Function'};
            app.ProblemselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationselectionDropDownValueChanged, true);
            app.ProblemselectionDropDown.Value = 'Sphere Function';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end